classdef practica_arduino < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ConexionLabel            matlab.ui.control.Label
        ConectarButton           matlab.ui.control.Button
        ActualizarPuertosButton  matlab.ui.control.Button
        PuertoDropDown           matlab.ui.control.DropDown
        PuertoDropDownLabel      matlab.ui.control.Label
        CamaraDropDown           matlab.ui.control.DropDown
        CamaraDropDownLabel      matlab.ui.control.Label
        ResultadoTextArea        matlab.ui.control.TextArea
        ParoButton               matlab.ui.control.Button
        CompararButton           matlab.ui.control.Button
        IniciarcamaraButton      matlab.ui.control.Button
        ReferenciaAxes           matlab.ui.control.UIAxes
        CamaraAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Cam = []
        ImagenActual = []
        CamaraActiva = false

        CarpetaBD = ''

        PuertoSerial = []
        Baudios = 115200

        ContadorCoincidencias = 0
    end
    
    methods (Access = private)

        % Buscar cámaras
        function actualizarCamaras(app)

            nombres = webcamlist;

            % Quitar datos anteriores antes de cambiar los elementos
            app.CamaraDropDown.ItemsData = [];

            if isempty(nombres)

                app.CamaraDropDown.Items = {'Sin cámaras'};
                app.CamaraDropDown.ItemsData = 0;
                app.CamaraDropDown.Value = 0;

                app.IniciarcamaraButton.Enable = 'off';
                return
            end

            etiquetas = cell(size(nombres));

            for k = 1:numel(nombres)
                etiquetas{k} = sprintf('%d - %s', k, nombres{k});
            end

            app.CamaraDropDown.Items = etiquetas;
            app.CamaraDropDown.ItemsData = 1:numel(nombres);
            app.CamaraDropDown.Value = 1;

            app.IniciarcamaraButton.Enable = 'on';

        end

        % Buscar puerto serial
        function actualizarPuertos(app)

            if ~isempty(app.PuertoSerial)
                return
            end

            puertos = cellstr(serialportlist("available"));

            if isempty(puertos)

                app.PuertoDropDown.Items = {'Sin puertos'};
                app.ConectarButton.Enable = 'off';

            else

                app.PuertoDropDown.Items = puertos;
                app.PuertoDropDown.Value = puertos{1};

                app.ConectarButton.Enable = 'on';

            end

        end

        % ESP32 
        function enviarComando(app, comando)

            if isempty(app.PuertoSerial)
                error('Primero conecta el ESP32.');
            end

            writeline(app.PuertoSerial, comando);

        end
        
        % ORB 
        function I = prepararImagen(app, I)

            if size(I, 3) == 3
                I = rgb2gray(I);
            end

            I = im2uint8(I);

            % Reducir imágenes grandes conservando su proporción
            escala = min(1, 800 / max(size(I, 1), size(I, 2)));

            if escala < 1
                I = imresize(I, escala);
            end

        end

        % Detener camara
        function detenerCamara(app)

            app.Cam = [];
            app.ImagenActual = [];

            app.CamaraDropDown.Enable = 'on';
            app.CompararButton.Enable = 'off';

        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            title(app.CamaraAxes, 'Cámara');
            title(app.ReferenciaAxes, 'Referencia BD');

            app.CompararButton.Enable = 'off';

            app.ConexionLabel.Text = 'Desconectado';
            app.ConectarButton.Text = 'Conectar ESP32';

            app.ContadorCoincidencias = 0;

            % Consultar cámaras
            try
                actualizarCamaras(app);

            catch ME
                app.IniciarcamaraButton.Enable = 'off';

                app.ResultadoTextArea.Value = {
                    'No se pudieron consultar las cámaras.'
                    ME.message
                    };
            end

            % Consultar puertos
            try
                actualizarPuertos(app);

            catch
                app.PuertoDropDown.Items = {'Sin puertos'};
                app.ConectarButton.Enable = 'off';
            end

            % Seleccionar referencias
            carpeta = uigetdir(pwd, 'Selecciona la carpeta BD');

            if ~isequal(carpeta, 0)

                app.CarpetaBD = carpeta;

                app.ResultadoTextArea.Value = {
                    'Carpeta BD seleccionada.'
                    'Selecciona una cámara y pulsa Inicio.'
                    };

            else

                app.ResultadoTextArea.Value = {
                    'No seleccionaste la carpeta BD.'
                    'Reabre la aplicación para seleccionarla.'
                    };

            end
        end

        % Button pushed function: ActualizarPuertosButton
        function ActualizarPuertosButtonPushed(app, event)
            try
                actualizarPuertos(app);

            catch ME
                app.ResultadoTextArea.Value = {
                    'Error al consultar puertos.'
                    ME.message
                    };
            end
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            if ~isempty(app.PuertoSerial)
                app.ResultadoTextArea.Value = ...
                    {'El puerto del ESP32 ya está abierto.'};
                return
            end

            try
                nombrePuerto = app.PuertoDropDown.Value;

                if strcmp(nombrePuerto, 'Sin puertos')
                    error('No hay un puerto disponible.');
                end

                app.PuertoSerial = serialport( ...
                    nombrePuerto, app.Baudios, ...
                    'Timeout', 2);

                configureTerminator(app.PuertoSerial, "LF");

                % Algunas placas se reinician al abrir el puerto
                pause(2);
                flush(app.PuertoSerial);

                % Comenzar con las salidas apagadas
                enviarComando(app, "PARO");

                app.ContadorCoincidencias = 0;

                app.ConexionLabel.Text = ...
                    ['Puerto abierto: ' nombrePuerto];

                app.ConectarButton.Enable = 'off';
                app.PuertoDropDown.Enable = 'off';
                app.ActualizarPuertosButton.Enable = 'off';

                app.ResultadoTextArea.Value = {
                    'Puerto serial abierto.'
                    'Comando PARO enviado.'
                    };

            catch ME
                app.PuertoSerial = [];

                app.ConexionLabel.Text = 'Desconectado';

                app.ResultadoTextArea.Value = {
                    'No se pudo abrir la conexión.'
                    ME.message
                    };
            end

        end

        % Button pushed function: IniciarcamaraButton
        function IniciarcamaraButtonPushed(app, event)
            % Evitar abrir otro ciclo de cámara
            if app.CamaraActiva
                return
            end

            try
                % Usar la cámara elegida en el selector
                indice = app.CamaraDropDown.Value;

                if isequal(indice, 0)
                    error('No hay una cámara disponible.');
                end

                app.Cam = webcam(indice);
                app.CamaraActiva = true;

                app.CamaraDropDown.Enable = 'off';
                app.IniciarcamaraButton.Enable = 'off';

                % Primera captura: crear la imagen una sola vez
                app.ImagenActual = snapshot(app.Cam);

                hVideo = imshow(app.ImagenActual, ...
                    'Parent', app.CamaraAxes);

                app.CompararButton.Enable = 'on';

                while app.CamaraActiva

                    app.ImagenActual = snapshot(app.Cam);
                    hVideo.CData = app.ImagenActual;

                    drawnow;

                end

            catch ME
                if isvalid(app)
                    app.ResultadoTextArea.Value = {
                        'Error de cámara.'
                        ME.message
                        };
                end
            end

            % Limpiar después de que el ciclo haya terminado
            if isvalid(app)

                app.CamaraActiva = false;
                app.Cam = [];
                app.ImagenActual = [];

                app.CompararButton.Enable = 'off';
                app.CamaraDropDown.Enable = 'on';
                app.IniciarcamaraButton.Enable = 'on';

                cla(app.CamaraAxes);
                cla(app.ReferenciaAxes);

                title(app.CamaraAxes, 'Cámara');
                title(app.ReferenciaAxes, 'Referencia BD');

            end


        end

        % Button pushed function: CompararButton
        function CompararButtonPushed(app, event)
              if ~app.CamaraActiva || isempty(app.ImagenActual)
                app.ResultadoTextArea.Value = {'Primero inicia la cámara.'};
                return
            end
        
            if isempty(app.CarpetaBD) || ~isfolder(app.CarpetaBD)
                app.ResultadoTextArea.Value = {'Selecciona una carpeta BD válida.'};
                return
            end
        
            app.CompararButton.Enable = 'off';
        
            try
                % Copiar el fotograma disponible sin abrir otra cámara
                captura = app.ImagenActual;
                actual = prepararImagen(app, captura);
        
                cla(app.ReferenciaAxes);
                title(app.ReferenciaAxes, 'Buscando coincidencia...');
        
                puntosActual = selectStrongest( ...
                    detectORBFeatures(actual), 1200);
        
                if puntosActual.Count < 10
                    app.ResultadoTextArea.Value = {
                        'Intente de nuevo.'
                        'La captura tiene pocos detalles.'
                    };
                else
                    [descActual, validosActual] = ...
                        extractFeatures(actual, puntosActual);
        
                    datos = imageDatastore(app.CarpetaBD);
        
                    mejorArchivo = '';
                    mejorCantidad = 0;
                    mejorPorcentaje = 0;
        
                    for k = 1:numel(datos.Files)
        
                        referencia = prepararImagen(app, readimage(datos, k));
        
                        puntosRef = selectStrongest( ...
                            detectORBFeatures(referencia), 1200);
        
                        if puntosRef.Count < 10
                            continue
                        end
        
                        [descRef, validosRef] = ...
                            extractFeatures(referencia, puntosRef);
        
                        pares = matchFeatures(descRef, descActual, ...
                            'Unique', true, ...
                            'MaxRatio', 0.75, ...
                            'MatchThreshold', 40);
        
                        if size(pares, 1) < 10
                            continue
                        end
        
                        ubicacionesRef = validosRef(pares(:, 1)).Location;
                        ubicacionesActual = validosActual(pares(:, 2)).Location;
        
                        [~, inliers, estado] = estgeotform2d( ...
                            ubicacionesRef, ubicacionesActual, ...
                            'projective', ...
                            'MaxDistance', 3, ...
                            'MaxNumTrials', 2000, ...
                            'Confidence', 99);
        
                        if estado ~= 0
                            continue
                        end
        
                        cantidad = nnz(inliers);
                        proporcion = cantidad / size(pares, 1);
        
                        % Umbrales iniciales: ajustar con pruebas reales
                        aceptada = cantidad >= 8 && proporcion >= 0.40;
        
                        if aceptada && cantidad > mejorCantidad
                            mejorCantidad = cantidad;
                            mejorPorcentaje = 100 * proporcion;
                            mejorArchivo = datos.Files{k};
                        end
        
                    end
        
                    if isempty(mejorArchivo)
        
                        title(app.ReferenciaAxes, 'Sin coincidencia');
                        app.ResultadoTextArea.Value = {'Intente de nuevo.'};
        
                    else
        
                        imshow(imread(mejorArchivo), ...
                            'Parent', app.ReferenciaAxes);
        
                        title(app.ReferenciaAxes, 'Referencia encontrada');
        
                        [~, nombre, extension] = fileparts(mejorArchivo);
        
                        resultado = {
                            ['Imagen: ' nombre extension]
                            sprintf('Consistencia geométrica: %.1f %%', ...
                                mejorPorcentaje)
                            sprintf('Correspondencias válidas: %d', ...
                                mejorCantidad)
                        };
        
                        if isempty(app.PuertoSerial)
        
                            resultado{end+1} = ...
                                'ESP32 desconectado. No se envió comando.';
        
                        elseif app.ContadorCoincidencias < 3

                            comandosMovimiento = [
                                "DERECHA_NORMAL"
                                "DERECHA_RAPIDA"
                                "IZQUIERDA_LENTA"
                                ];

                            siguienteMovimiento = app.ContadorCoincidencias + 1;

                            comandoMovimiento = comandosMovimiento(siguienteMovimiento);

                            enviarComando(app, comandoMovimiento);

                            app.ContadorCoincidencias = siguienteMovimiento;

                            resultado{end+1} = ...
                                ['Comando enviado: ' char(comando)];
        
                        else
        
                            resultado{end+1} = ...
                                'Secuencia terminada. Pulsa Paro para reiniciar.';
        
                        end
        
                        app.ResultadoTextArea.Value = resultado;
        
                    end
                end
        
            catch ME
        
                app.ResultadoTextArea.Value = {
                    'Error durante la comparación o el envío.'
                    ME.message
                };
        
            end
        
            if isvalid(app) && app.CamaraActiva
                app.CompararButton.Enable = 'on';
            end


        end

        % Button pushed function: ParoButton
        function ParoButtonPushed(app, event)
            % Pedir al ciclo de Inicio que termine
            app.CamaraActiva = false;
            app.CompararButton.Enable = 'off';

            app.ContadorCoincidencias = 0;

            aviso = 'Cámara detenida. ESP32 desconectado.';

            if ~isempty(app.PuertoSerial)
                try
                    enviarComando(app, "PARO");
                    aviso = 'Cámara detenida. Comando PARO enviado.';
                catch ME
                    aviso = ['No se pudo enviar PARO: ' ME.message];
                end
            end

            % Si Inicio sigue ejecutándose, él limpiará al salir.
            % Si ya terminó, limpiar directamente también es válido.
            cla(app.CamaraAxes);
            cla(app.ReferenciaAxes);

            title(app.CamaraAxes, 'Cámara');
            title(app.ReferenciaAxes, 'Referencia BD');

            app.ResultadoTextArea.Value = {
                aviso
                'Contador reiniciado.'
                };

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.PuertoSerial)

                try
                    enviarComando(app, "PARO");

                catch ME
                    app.ResultadoTextArea.Value = {
                        'No se pudo enviar PARO.'
                        'Revisa o detén el ESP32 antes de cerrar.'
                        ME.message
                        };
                    return
                end

                app.PuertoSerial = [];

            end

            detenerCamara(app);
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CamaraAxes = uiaxes(app.UIFigure);
            title(app.CamaraAxes, 'Title')
            xlabel(app.CamaraAxes, 'X')
            ylabel(app.CamaraAxes, 'Y')
            zlabel(app.CamaraAxes, 'Z')
            app.ReferenciaAxes = uiaxes(app.UIFigure);
            title(app.ReferenciaAxes, 'Title')
            xlabel(app.ReferenciaAxes, 'X')
            ylabel(app.ReferenciaAxes, 'Y')
            zlabel(app.ReferenciaAxes, 'Z')
            app.IniciarcamaraButton = uibutton(app.UIFigure, 'push');
            app.IniciarcamaraButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarcamaraButtonPushed, true);
            app.IniciarcamaraButton.Text = 'Iniciar Camara';
            app.CompararButton = uibutton(app.UIFigure, 'push');
            app.CompararButton.ButtonPushedFcn = createCallbackFcn(app, @CompararButtonPushed, true);
            app.CompararButton.Text = 'Comparar';
            app.ParoButton = uibutton(app.UIFigure, 'push');
            app.ParoButton.ButtonPushedFcn = createCallbackFcn(app, @ParoButtonPushed, true);
            app.ParoButton.Text = 'Paro';
            app.ResultadoTextArea = uitextarea(app.UIFigure);
            app.CamaraDropDownLabel = uilabel(app.UIFigure);
            app.CamaraDropDownLabel.Text = 'Camara';
            app.CamaraDropDown = uidropdown(app.UIFigure);
            app.CamaraDropDown.Items = {};
            app.CamaraDropDown.Value = {};
            app.PuertoDropDownLabel = uilabel(app.UIFigure);
            app.PuertoDropDownLabel.Text = 'Puertos';
            app.PuertoDropDown = uidropdown(app.UIFigure);
            app.ActualizarPuertosButton = uibutton(app.UIFigure, 'push');
            app.ActualizarPuertosButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarPuertosButtonPushed, true);
            app.ActualizarPuertosButton.Text = 'Actualizar puertos';
            app.ConectarButton = uibutton(app.UIFigure, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar ESP32';
            app.ConexionLabel = uilabel(app.UIFigure);
            app.ConexionLabel.Text = 'Desconectado';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = practica_arduino

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end